classdef tire_data_visualization_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        RoundDropDownLabel             matlab.ui.control.Label
        RoundDropDown                  matlab.ui.control.DropDown
        TireDropDownLabel              matlab.ui.control.Label
        TireDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel             matlab.ui.control.Label
        XaxisDropDown                  matlab.ui.control.DropDown
        YaxisDropDownLabel             matlab.ui.control.Label
        YaxisDropDown                  matlab.ui.control.DropDown
        HoldOnSwitchLabel              matlab.ui.control.Label
        HoldOnSwitch                   matlab.ui.control.Switch
        ClearButton                    matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        HyTechTireDataVisualizationToolLabel  matlab.ui.control.Label
        RunDropDownLabel               matlab.ui.control.Label
        RunDropDown                    matlab.ui.control.DropDown
        RunGuideButton                 matlab.ui.control.Button
        SameRunButton                  matlab.ui.control.Button
        ZoomSwitchLabel                matlab.ui.control.Label
        ZoomSwitch                     matlab.ui.control.Switch
        PanSwitchLabel                 matlab.ui.control.Label
        PanSwitch                      matlab.ui.control.Switch
        ChangingVariableDropDownLabel  matlab.ui.control.Label
        ChangingVariableDropDown       matlab.ui.control.DropDown
        TextArea                       matlab.ui.control.TextArea
        ColdtoHotSweepsLabel           matlab.ui.control.Label
        ColdtoHotSweepsSwitch          matlab.ui.control.Switch
        PlotTypeSwitchLabel            matlab.ui.control.Label
        PlotTypeSwitch                 matlab.ui.control.Switch
        SpringRateTestsSwitchLabel     matlab.ui.control.Label
        SpringRateTestsSwitch          matlab.ui.control.Switch
        PullOutFigureButton            matlab.ui.control.Button
        RoundSpreadSheetButton         matlab.ui.control.Button
        BeginnerGuideButton            matlab.ui.control.Button
        ExporttoExcelButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        data %holds data after importing
        exportData %holds data to export
        Selected_Round %int value for chosen round
        Selected_Tire %int value for chosen tire
        Selected_Test %int value for chosen test
        Selected_X %string name of chosen x variable
        Selected_X_Units %string units for chosen x variable
        Selected_Y %string name of chosen y variable
        Selected_Y_Units %string units for chosen y variable
        Hold %string for hold switch
        Test_Type %string for test type switch
        Rounds %vector of int values for all rounds
        Tires %struct of matrices of tire load strings for every round
        TireNames %matrix of tire names for every round
        Run %Integer of Run
        Variables %matrix of variable names and units
        S_Vals %Start and Stop X Values provided by user
        plot_count %Integer of number of plots since last clear
        changing_var
        changing_var_units
        C2HSwTog %Cold to Hot Sweeps Toggle
        SpringRateTog %Spring Rate Tests Toggle
        PlotType
    end
    
    methods (Access = private)
        
        function results = selectDataByMouse(app)
            
            Tire_Matrix = app.Tires.(strcat('R',app.Selected_Round));
            
            %Finds string to load .mat file
            loadString = Tire_Matrix(app.Selected_Tire,str2double(app.Run));
            
            %Tests if .mat file exists
            if loadString == "No Run"
                %Sends error message
                uialert(app.UIFigure,"No Data is available for this tire","No Run");
                
                return;
            else
                %Imports data from correct .mat file based on selected tire and test from tire string matrix
                app.data = importdata(loadString);
            end
            
            f = figure;
            
            subplot(6,1,1);%N(speed)
            plot(app.data.N)
            title('Speed')
            ylabel('rpm')
            
            subplot(6,1,2);%IA(inclination angle)
            plot(app.data.IA)
            title('Inclination Angle')
            ylabel('deg')
            
            subplot(6,1,3);%FZ(Normal Load)
            plot(app.data.FZ)
            title('Normal Load')
            ylabel('N')
            
            subplot(6,1,4);%P(Pressure)
            plot(app.data.P)
            title('Pressure')
            ylabel('Kpa')
            
            subplot(6,1,5);%SA(Slip Angle)
            plot(app.data.SA)
            title('Slip Angle')
            ylabel('deg')
            
            subplot(6,1,6);%SL(Slip Ratio)
            plot(app.data.SL)
            title('Slip Ratio')
            ylabel('unitless')
            
            [app.S_Vals,~] = ginput(2); %Promts user to select start and stop x values
            app.S_Vals = [round(app.S_Vals(1)),round(app.S_Vals(2))]; %Rounds values to integer
            if app.S_Vals(1) > app.S_Vals(2)
                app.S_Vals = [app.S_Vals(2),app.S_Vals(1)];
            end
            
            close(f); %Closes figure
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TireDropDown.Visible = 'off';
            app.ExporttoExcelButton.Visible = 'off';
            app.RoundSpreadSheetButton.Visible = 'off';
            app.RunDropDown.Visible = 'off';
            app.RunGuideButton.Visible = 'off';
            app.SameRunButton.Visible = 'off';
            app.ChangingVariableDropDownLabel.Visible = 'off';
            app.ChangingVariableDropDown.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.ColdtoHotSweepsSwitch.Visible = 'off';
            app.ColdtoHotSweepsLabel.Visible = 'off';
            app.SpringRateTestsSwitch.Visible = 'off';
            app.SpringRateTestsSwitchLabel.Visible = 'off';
            
            app.PlotType = 'Raw Data';
            
            %Variable names with units
            app.Variables = ["AMBTMP"," degC";
                "ET"," sec";
                "FX"," N";
                "FY"," N";
                "FZ"," N";
                "IA"," deg";
                "MX"," N-m";
                "MZ"," N-m";
                "N"," rpm";
                "NFX","";
                "NFY","";
                "P"," kPa";
                "RE"," cm";
                "RL"," cm";
                "RST"," degC";
                "SA"," deg";
                "SL"," based on RE";
                "SR"," based on RL";
                "TSTC"," degC";
                "TSTI"," degC";
                "TSTO"," degC";
                "V"," kph";];
            
            %Sets Hold to off
            app.Hold = "Off";
            
            %Sets Run to 1
            app.Run = 1;
            
            %List of rounds
            app.Rounds = [7];
            
            %List of Tires by round, tire name
            app.TireNames = ["6","Hoosier 18.0 x 7.5 10 R25B (Item 43105), 7 inch rim", "Hoosier 18.0 x 7.5 10 R25B (Item 43105), 8 inch rim", "Hoosier 18.0 x 6.0 10 R25B (Item 43101), 6 in rim", "Hoosier 18.0 x 6.0 10 R25B (Item 43101), 7 inch rim", "Hoosier 6.0 / 18.0 10 LCO C2000 (Item 41100), 6 inch rim", "Hoosier 6.0 / 18.0 10 LCO C2000 (Item 41100), 7 inch rim","","";
                "7","Avon 7.0/16.0-10, 7 inch rim","Avon 6.2/20.0-13, 8 inch rim","Avon 8.2/20.0-13, 8 inch rim","Avon 8.2/20.0-13, 9 inch rim","Avon 7.2/20.0-13, 7 inch rim","Avon 7.2/20.0-13, 8 inch rim","Avon 6.2/20.0-13, 6 inch rim","Avon 6.2/20.0-13, 7 inch rim";
                "8","Hoosier 43075 16x7.5-10 R25B, 7 inch rim", "Hoosier 43075 16x7.5-10 R25B, 8 inch rim", "Hoosier 43070 16x6.0-10 R25B, 6 in rim", "Hoosier 43070 16x6.0-10 R25B, 7 inch rim", "Hoosier 43075 16x7.5-10 LCO, 8 inch rim", "Hoosier 43075 16x7.5-10 LCO, 7 inch rim","Hoosier 43070 16x6.0-10 LCO, 6 inch rim","Hoosier 43070 16x6.0-10 LCO, 7 inch rim"];
            
            %Matrix of tires for round 6 by data file names. Each row is data for 1 tire
            TiresR6 = ["B1654raw20.mat","B1654raw21.mat","B1654raw22.mat","B1654raw34.mat","B1654raw35.mat","B1654raw36.mat"; %Tire 1: Hoosier 18.0 x 7.5 10 R25B (Item 43105), 7 inch rim
                "B1654raw23.mat","B1654raw24.mat","B1654raw25.mat","B1654raw37.mat","B1654raw38.mat","B1654raw39.mat"; %Tire 2: Hoosier 18.0 x 7.5 10 R25B (Item 43105), 8 inch rim
                "B1654raw28.mat","B1654raw29.mat","B1654raw40.mat","B1654raw41.mat","No Run","No Run"; %Tire 3: Hoosier 18.0 x 6.0 10 R25B (Item 43101), 6 inch rim
                "B1654raw26.mat","B1654raw27.mat","B1654raw42.mat","B1654raw43.mat","No Run","No Run"; %Tire 4: Hoosier 18.0 x 6.0 10 R25B (Item 43101), 7 inch rim
                "B1654raw30.mat","B1654raw31.mat","B1654raw44.mat","B1654raw45.mat","No Run","No Run"; %Tire 5: Hoosier 6.0 / 18.0 10 LCO C2000 (Item 41100), 6 inch rim
                "B1654raw32.mat","B1654raw33.mat","B1654raw46.mat","B1654raw47.mat","No Run","No Run"]; %Tire 6: Hoosier 6.0 / 18.0 10 LCO C2000 (Item 41100), 7 inch rim
            
            %Matrix of tires for round 7 by data file names. Each row is data for 1 tire
            TiresR7 = ["B1706raw1.mat","B1706raw2.mat","B1706raw3.mat","No Run","No Run","No Run"; %Tire 1: Avon 7.0/16.0-10, 7 inch rim
                "B1706raw4.mat","B1706raw5.mat","B1706raw6.mat","No Run","No Run","No Run"; %Tire 2: Avon 6.2/20.0-13, 8 inch rim
                "B1706raw7.mat","B1706raw8.mat","B1706raw9.mat","B1706run38.mat","B1706run39.mat","B1706run40.mat"; %Tire 3: Avon 8.2/20.0-13, 8 inch rim
                "B1706raw11.mat","B1706raw12.mat","B1706raw13.mat","B1706run41.mat","B1706run42.mat","B1706run43.mat"; %Tire 4: Avon 8.2/20.0-13, 9 inch rim
                "B1706raw14.mat","B1706raw15.mat","B1706raw16.mat","B1706run32.mat","B1706run33.mat","B1706run34.mat"; %Tire 5: Avon 7.2/20.0-13, 7 inch rim
                "B1706raw17.mat","B1706raw18.mat","B1706raw19.mat","B1706run35.mat","B1706run36.mat","B1706run37.mat"; %Tire 6: Avon 7.2/20.0-13, 8 inch rim
                "B1706raw20.mat","B1706raw21.mat","B1706raw22.mat","B1706run26.mat","B1706run27.mat","B1706run28.mat"; %Tire 7: Avon 6.2/20.0-13, 6 inch rim
                "B1706raw23.mat","B1706raw24.mat","B1706raw25.mat","B1706run29.mat","B1706run30.mat","B1706run31.mat"]; %Tire 8: Avon 6.2/20.0-13, 7 inch rim
            
            %Matrix of tires for round 7 by data file names. Each row is data for 1 tire
            TiresR8 = ["B1965run1.mat","B1965run2.mat","B1965run4.mat","No Run","No Run","No Run"; %Tire 1: Hoosier 43075 16x7.5-10 R25B, 7 inch rim
                "B1965run5.mat","B1965run6.mat","B1965run7.mat","No Run","No Run","No Run"; %Tire 2: Hoosier 43075 16x7.5-10 R25B, 8 inch rim
                "B1965run8.mat","B1965run9.mat","B1965run10.mat","No Run","No Run","No Run"; %Tire 3: Hoosier 43070 16x6.0-10 R25B, 6 inch rim
                "B1965run11.mat","B1965run12.mat","B1965run13.mat","No Run","No Run","No Run"; %Tire 4: Hoosier 43070 16x6.0-10 R25B, 7 inch rim
                "B1965run14.mat","B1965run15.mat","B1965run16.mat","No Run","No Run","No Run"; %Tire 5: Hoosier 43075 16x7.5-10 LCO, 8 inch rim
                "B1965run17.mat","B1965run18.mat","B1965run19.mat","No Run","No Run","No Run"; %Tire 6: Hoosier 43075 16x7.5-10 LCO, 7 inch rim
                "B1965run20.mat","B1965run21.mat","B1965run22.mat","No Run","No Run","No Run"; %Tire 7: Hoosier 43070 16x6.0-10 LCO, 6 inch rim
                "B1965run23.mat","B1965run24.mat","B1965run25.mat","No Run","No Run","No Run"]; %Tire 8: Hoosier 43070 16x6.0-10 LCO, 7 inch rim
            
            %Put Tire Matrix in app variable
            app.Tires = struct('R6',TiresR6,'R7',TiresR7,'R8',TiresR8);
            
            %Set Clear Counter to 0
            app.plot_count = 0;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x = app.data.(app.Selected_X)(app.S_Vals(1):app.S_Vals(2)); %Gets chosen X values
            y = app.data.(app.Selected_Y)(app.S_Vals(1):app.S_Vals(2)); %Gets chosen Y values
            
            line_style = {'-','--',':','-.','+','o','*','.','x','s','d','^','v','>','<','p','h'};
            
            if str2num(app.Run) <= 4
                n=5;
            else
                n=4;
            end
            
            deg=5; %degree of polynomial fit
            
            if app.Selected_X == 'SA' && app.Selected_Y == 'FY'
                title = 'Lateral Force vs Slip Angle';
                second_var = 'FZ';
                sv_units = 'N';
                
                code = '1';
            elseif app.Selected_X == 'SA' && app.Selected_Y == 'NFY'
                title = 'Normalized Lateral Force vs Slip Angle';
                second_var = 'IA';
                sv_units = 'deg';
                n = 1;
                deg=4;
                code = '1';
            elseif app.Selected_X == 'SA' && app.Selected_Y == 'NFX'
                title = 'Normalized Longitudinal Force vs Slip Angle';
                second_var = 'IA';
                sv_units = 'deg';
                n = 1;
                deg=4;
                code = '1';
            elseif app.Selected_X == 'SA' && app.Selected_Y == 'MX'
                title = 'Overturning Moment vs Slip Angle';
                second_var = 'FZ';
                sv_units = 'N';
                
                code = '1';
            elseif app.Selected_X == 'SA' && app.Selected_Y == 'MZ'
                title = 'Aligning Torque vs Slip Angle';
                second_var = 'FZ';
                sv_units = 'N';
                
                code = '1';
            elseif (app.Selected_X == 'SL' || app.Selected_X == 'SR') && app.Selected_Y == 'FX'
                title = 'Longitudinal Force vs Slip Ratio';
                second_var = 'FZ';
                sv_units = 'N';
                
                code = '1';
            elseif (app.Selected_X == 'SL' || app.Selected_X == 'SR') && app.Selected_Y == 'MX'
                title = 'Overturning Moment vs Slip Ratio';
                second_var = 'FZ';
                sv_units = 'N';
                
                code = '1';
            elseif (app.Selected_X == 'SL' || app.Selected_X == 'SR') && app.Selected_Y == 'FY'
                title = 'Lateral Force vs Slip Ratio';
                second_var = 'FZ';
                sv_units = 'N';
                
                code = '1';
            elseif (app.Selected_X == 'SL' || app.Selected_X == 'SR') && app.Selected_Y == 'NFY'
                title = 'Normalized Lateral Force vs Slip Ratio';
                second_var = 'IA';
                sv_units = 'deg';
                n = 1;
                code = '1';
            elseif (app.Selected_X == 'SL' || app.Selected_X == 'SR') && app.Selected_Y == 'NFX'
                title = 'Normalized Longitudinal Force vs Slip Ratio';
                second_var = 'IA';
                sv_units = 'deg';
                n = 1;
                code = '1';
            elseif (app.Selected_X == 'SL' || app.Selected_X == 'SR') && app.Selected_Y == 'MZ'
                title = 'Aligning Torque vs Slip Ratio';
                second_var = 'FZ';
                sv_units = 'N';
                
                code = '1';
            elseif app.Selected_X == 'IA' && app.Selected_Y == 'FY'
                title = 'Lateral Force vs Inclination Angle';
                second_var = 'FZ';
                sv_units = 'N';
                n=2;
                
                code = '2';
            elseif app.Selected_X == 'IA' && app.Selected_Y == 'NFY'
                title = 'Normalized Lateral Force vs Inclination Angle';
                second_var = 'FZ';
                sv_units = 'N';
                n=2;
                
                code = '2';
            elseif app.Selected_X == 'IA' && app.Selected_Y == 'NFX'
                title = 'Normalized Longitudinal Force vs Inclination Angle';
                second_var = 'FZ';
                sv_units = 'N';
                n=2;
                
                code = '2';
            elseif app.Selected_X == 'IA' && app.Selected_Y == 'MX'
                title = 'Overnturning Moment vs Inclination Angle';
                second_var = 'FZ';
                sv_units = 'N';
                n=2;
                
                code = '2';
            elseif app.Selected_X == 'IA' && app.Selected_Y == 'MZ'
                title = 'Aligning Torque vs Inclination Angle';
                second_var = 'FZ';
                sv_units = 'N';
                n=2;
                
                code = '2';
            elseif app.Selected_X == 'IA' && app.Selected_Y == 'FX'
                title = 'Longitudinal Force vs Inclination Angle';
                second_var = 'FZ';
                sv_units = 'N';
                n=2;
                
                code = '2';
            elseif app.Selected_X == 'RL' && app.Selected_Y == 'FZ'
                title = 'Vertical Load vs Loaded Radius';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                deg=1;
                code = '1';
            elseif app.Selected_X == 'RL' && app.Selected_Y == 'FY'
                title = 'Lateral Force vs Loaded Radius';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '1';
            elseif app.Selected_X == 'RL' && app.Selected_Y == 'FX'
                title = 'Longitudinal Force vs Loaded Radius';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '1';
            elseif app.Selected_X == 'RL' && app.Selected_Y == 'MX'
                title = 'Overturning Moment vs Loaded Radius';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                deg=1;
                code = '1';
            elseif app.Selected_X == 'RL' && app.Selected_Y == 'MZ'
                title = 'Aligning Torque vs Loaded Radius';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                deg=1;
                code = '1';
            elseif app.Selected_X == 'RL' && app.Selected_Y == 'NFX'
                title = 'Normalized Longitudinal Force vs Loaded Radius';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                deg=2;
                code = '1';
            elseif app.Selected_X == 'RL' && app.Selected_Y == 'NFY'
                title = 'Normalized Lateral Force vs Loaded Radius';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                deg=2;
                code = '1';
            elseif app.Selected_X == 'ET' && app.Selected_Y == 'TSTC'
                title = 'Tire Surface Temperature--Center vs Elapsed time for the test';
                second_var = 'FZ';
                sv_units = ' N';
                n=1;
                deg=2;
                code = '1';
            elseif app.Selected_X == 'FZ' && app.Selected_Y == 'TSTC'
                title = 'Tire Surface Temperature--Center vs Normal Load';
                second_var = 'ET';
                sv_units = ' seconds';
                n=1;
                deg=2;
                code = '1';
            elseif app.Selected_X == 'FZ' && app.Selected_Y == 'FY'
                title = 'Lateral Force vs Normal Load';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '3';
            elseif app.Selected_X == 'FZ' && app.Selected_Y == 'FX'
                title = 'Longitudinal Force vs Normal Load';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '3';
            elseif app.Selected_X == 'FZ' && app.Selected_Y == 'MX'
                title = 'Overturning Moment vs Normal Load';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '3';
            elseif app.Selected_X == 'FZ' && app.Selected_Y == 'MZ'
                title = 'Aligning Torque vs Normal Load';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '3';
            elseif app.Selected_X == 'FZ' && app.Selected_Y == 'NFX'
                title = 'Normalized Longitudinal Force vs Normal Load';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '3';
            elseif app.Selected_X == 'FZ' && app.Selected_Y == 'NFY'
                title = 'Normalized Lateral Force vs Normal Load';
                second_var = 'IA';
                sv_units = ' deg';
                n=3;
                
                code = '3';
            else
                title = strcat(app.Selected_Y,{' '},'vs',{' '},app.Selected_X);
                code = 'd';
            end
            
            if length(app.C2HSwTog) == 3
                n=1;
            end
            
            if length(app.SpringRateTog) == 3
                n=3;
                second_var = 'IA';
                code = '1';
            end
            
            if code ~= 'd'
                sv = app.data.(second_var)(app.S_Vals(1):app.S_Vals(2));
            end
            
            if code == '2'
                ind = findchangepts(sv,'MaxNumChanges',9);
                one = [x(1:ind(1))',flip(x(ind(2):ind(3))',2),x(ind(4):ind(5))',flip(x(ind(6):ind(7))',2),x(ind(8):ind(9))'];
                two = [x(ind(1):ind(2))',flip(x(ind(3):ind(4))',2),x(ind(5):ind(6))',flip(x(ind(7):ind(8))',2),x(ind(9):end)'];
                x = [one,two]';
                one = [y(1:ind(1))',flip(y(ind(2):ind(3))',2),y(ind(4):ind(5))',flip(y(ind(6):ind(7))',2),y(ind(8):ind(9))'];
                two = [y(ind(1):ind(2))',flip(y(ind(3):ind(4))',2),y(ind(5):ind(6))',flip(y(ind(7):ind(8))',2),y(ind(9):end)'];
                y = [one,two]';
                one = [sv(1:ind(1))',flip(sv(ind(2):ind(3))',2),sv(ind(4):ind(5))',flip(sv(ind(6):ind(7))',2),sv(ind(8):ind(9))'];
                two = [sv(ind(1):ind(2))',flip(sv(ind(3):ind(4))',2),sv(ind(5):ind(6))',flip(sv(ind(7):ind(8))',2),sv(ind(9):end)'];
                sv = [one,two]';
            end
            
            if code == '3'
                %find indexes of changes between loads during run
                ind = findchangepts(y,'MaxNumChanges',3);
                %separates each load into its own variable
                x_one = x(1:ind(1));
                x_two = x(ind(2):ind(3));
                x_three = x(ind(1):ind(2));
                x_four = x(ind(3):end);
                
                y_one = y(1:ind(1));
                y_two = y(ind(2):ind(3));
                y_three = y(ind(1):ind(2));
                y_four = y(ind(3):end);
                
                %finds max of each load
                [~,ind1] = max(y_one);
                [~,ind2] = max(y_two);
                [~,ind3] = max(y_three);
                [~,ind4] = max(y_four);
                
                xvals = [x_one(ind1),x_two(ind2),x_three(ind3),x_four(ind4)];
                yvals = [y_one(ind1),y_two(ind2),y_three(ind3),y_four(ind4)];
                n=3; %changed from 0 might break something
                deg = 2;
            end
            
            if code == '1' || code == '2' || code == '3'
                tempcolors = ['b','r','g','k','m','c','y','w'];
                colors = tempcolors(1:n);
                
                if n>1
                    indices = findchangepts(sv,'MaxNumChanges',n-1);
                    tests = zeros(1,n-1);
                    x_vals{1,1} = x(1:indices(1));
                    y_vals{1,1} = y(1:indices(1));
                    tests(1,1) =  round(-1*mean(sv(1:indices(1))));
                    
                    i = 2;
                    while i <= length(indices)
                        x_vals{1,i} = x(indices(i-1):indices(i));
                        y_vals{1,i} = y(indices(i-1):indices(i));
                        tests(1,i) = round(-1*mean(sv(indices(i-1):indices(i))));
                        i = i+1;
                    end
                    x_vals{1,n} = x(indices(i-1):end);
                    y_vals{1,n} = y(indices(i-1):end);
                    tests(1,n) = round(-1*mean(sv(indices(i-1):end)));
                else
                    x_vals{1,1} = x;
                    y_vals{1,1} = y;
                    tests{1,1} = round(-1*mean(sv));
                end
                i = 1;
                while i <= size(x_vals,2)
                    p{1,i} = polyfit(x_vals{1,i},y_vals{1,i},deg);
                    i = i+1;
                end
                
                if length(app.Hold) == 1 || length(app.Hold) == 3
                    cla(app.UIAxes)
                    app.plot_count = 0;
                    addPlotCount = 'f';
                else
                    addPlotCount = 't';
                end
                hold(app.UIAxes,'on')
                i = 1;
                while i <= size(p,2)
                    line_type = strcat(colors(i),line_style{1,app.plot_count + 1});
                    if length(app.PlotType) == 7
                        plot(app.UIAxes,x_vals{1,i},polyval(p{1,i},x_vals{1,i}),line_type,'linewidth',2)
                    else
                        plot(app.UIAxes,x_vals{1,i},y_vals{1,i},line_type,'linewidth',2)
                    end
                    i = i+1;
                end
                
                app.exportData = [{x_vals}, {y_vals}]; %build matrix for excel exporting
                
                
                app.UIAxes.Title.String = title;
                xlabel(app.UIAxes,app.Selected_X + app.Selected_X_Units)
                ylabel(app.UIAxes,app.Selected_Y + app.Selected_Y_Units)
                hold(app.UIAxes,'off')
                i=1;
                leg = cell(n,1);
                if n>1
                    while i<=n
                        leg{i} = strcat(num2str(tests(1,i)),sv_units);
                        i=i+1;
                    end
                else
                    while i<=n
                        leg{i} = strcat(num2str(tests{1,i}),sv_units);
                        i=i+1;
                    end
                end
                legend(app.UIAxes,leg)
                
                if length(app.Hold) == 2
                    if length(app.changing_var) == 4 & app.changing_var == 'Tire'
                        if app.plot_count == 0
                            app.TextArea.Value = strcat(app.data.tireid,{' '},line_style{1,app.plot_count + 1});
                        else
                            app.TextArea.Value = [app.TextArea.Value;strcat(app.data.tireid,{' '},line_style{1,app.plot_count + 1})];
                        end
                    else
                        avg_val = round(mean(app.data.(app.changing_var)(app.S_Vals(1):app.S_Vals(2))));
                        if app.plot_count == 0
                            app.TextArea.Value = strcat(app.changing_var,'=',num2str(avg_val),app.changing_var_units,{' '},line_style{1,app.plot_count + 1});
                        else
                            app.TextArea.Value = [app.TextArea.Value;strcat(app.changing_var,'=',num2str(avg_val),app.changing_var_units,{' '},line_style{1,app.plot_count + 1})];
                        end
                    end
                end
                
                if addPlotCount == 't'
                    app.plot_count = app.plot_count + 1;
                    addPlotCount = 'f';
                end
            else
                %When code == d just plot raw data
                plot(app.UIAxes,x,y);
                app.UIAxes.Title.String = title;
                xlabel(app.UIAxes,app.Selected_X + app.Selected_X_Units)
                ylabel(app.UIAxes,app.Selected_Y + app.Selected_Y_Units)
            end

            app.ExporttoExcelButton.Visible = 'on';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %Clears plot
            cla(app.UIAxes);
            app.plot_count = 0;
        end

        % Value changed function: RoundDropDown
        function RoundDropDownValueChanged(app, event)
            %Sets selected round
            app.Selected_Round = event.Value;
            
            %Sets possible options for Tire Drop Down to corresponding TireNames row
            [Tire_index , ~] = find(app.TireNames == app.Selected_Round); %Find row of round in TireNames and stores index in Tire_index
            app.TireDropDown.Items = app.TireNames(Tire_index,2:end); %Set possible options for TireDropDown
            
            %Sets 1st Tire as selected tire
            app.Selected_Tire = 1;
            
            app.TireDropDown.Visible = 'on';
            app.RoundSpreadSheetButton.Visible = 'on';
            
        end

        % Value changed function: TireDropDown
        function TireDropDownValueChanged(app, event)
            %Sets Selected_Tire
            [~, NumTire] = find(app.TireNames == event.Value); %Finds column of test chosen by user
            app.Selected_Tire = NumTire-1; %Selected_Tire = tire number in round and corresponding row of TiresR# matrix
            
            app.RunGuideButton.Visible = 'on';
            app.SameRunButton.Visible = 'on';
            app.RunDropDown.Visible = 'on';
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            %find row of choice
            row = app.Variables == event.Value;
            
            app.Selected_X = app.Variables(row,1); %Set Selected_X to selected variable name
            app.Selected_X_Units = app.Variables(row,2); %Set Selected_X_Units to selected variable units
            
            if app.Selected_X == 'SA'
                app.ColdtoHotSweepsSwitch.Visible = 'on';
                app.ColdtoHotSweepsLabel.Visible = 'on';
            else
                app.ColdtoHotSweepsSwitch.Visible = 'off';
                app.ColdtoHotSweepsLabel.Visible = 'off';
                app.C2HSwTog = 'No';
            end
            
            if app.Selected_X == 'FZ'
                app.SpringRateTestsSwitch.Visible = 'on';
                app.SpringRateTestsSwitchLabel.Visible = 'on';
            else
                app.SpringRateTestsSwitch.Visible = 'off';
                app.SpringRateTestsSwitchLabel.Visible = 'off';
                app.SpringRateTog = 'No';
            end
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            %find row of choice
            row = app.Variables == event.Value;
            
            app.Selected_Y = app.Variables(row,1); %Set Selected_Y to selected variable name
            app.Selected_Y_Units = app.Variables(row,2); %Set Selected_Y_Units to selected variable units
            
        end

        % Value changed function: HoldOnSwitch
        function HoldOnSwitchValueChanged(app, event)
            %Changes value of hold variable
            app.Hold = event.Value;
            if length(app.Hold) == 2
                app.ChangingVariableDropDownLabel.Visible = 'on';
                app.ChangingVariableDropDown.Visible = 'on';
                app.TextArea.Visible = 'on';
                app.changing_var = 'Tire';
            else
                app.ChangingVariableDropDownLabel.Visible = 'off';
                app.ChangingVariableDropDown.Visible = 'off';
                app.TextArea.Visible = 'off';
                app.TextArea.Value = ' ';
            end
            
        end

        % Value changed function: RunDropDown
        function RunDropDownValueChanged(app, event)
            app.Run = app.RunDropDown.Value;
            if app.Run ~= ' '
                selectDataByMouse(app);
            end
        end

        % Button pushed function: RunGuideButton
        function RunGuideButtonPushed(app, event)
            %if app.Selected_Round == '7'
            I = imread('Round_7_Run_Guide.PNG');
            imshow(I);
            %end
        end

        % Button pushed function: SameRunButton
        function SameRunButtonPushed(app, event)
            if app.Run ~= ' '
                selectDataByMouse(app);
            end
        end

        % Value changed function: ZoomSwitch
        function ZoomSwitchValueChanged(app, event)
            value = app.ZoomSwitch.Value;
            if length(value) == 2
                zoom(app.UIAxes,'on')
            else
                zoom(app.UIAxes,'off')
            end
        end

        % Value changed function: PanSwitch
        function PanSwitchValueChanged(app, event)
            value = app.PanSwitch.Value;
            if length(value) == 2
                pan(app.UIAxes,'on')
            else
                pan(app.UIAxes,'off')
            end
        end

        % Value changed function: ChangingVariableDropDown
        function ChangingVariableDropDownValueChanged(app, event)
            app.changing_var = app.ChangingVariableDropDown.Value;
            app.TextArea.Value = " ";
            if event.Value ~= 4 | event.Value ~= 'Tire'
                row = app.Variables == event.Value;
                app.changing_var_units = app.Variables(row,2);
            end
            
        end

        % Value changed function: ColdtoHotSweepsSwitch
        function ColdtoHotSweepsSwitchValueChanged(app, event)
            value = app.ColdtoHotSweepsSwitch.Value;
            app.C2HSwTog = value;
        end

        % Value changed function: PlotTypeSwitch
        function PlotTypeSwitchValueChanged(app, event)
            value = app.PlotTypeSwitch.Value;
            app.PlotType = value;
        end

        % Value changed function: SpringRateTestsSwitch
        function SpringRateTestsSwitchValueChanged(app, event)
            value = app.SpringRateTestsSwitch.Value;
            app.SpringRateTog = value;
        end

        % Button pushed function: PullOutFigureButton
        function PullOutFigureButtonPushed(app, event)
            % Create new figure and new axes
            figure
            axNew = axes;
            % Copy all objects from UIAxes to new axis
            copyobj(app.UIAxes.Children, axNew)
            % Save all parameters of the UIAxes
            uiAxParams = get(app.UIAxes);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axNew));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            % set editable params on new axes
            set(axNew, uiAxGoodParams)
            
        end

        % Button pushed function: RoundSpreadSheetButton
        function RoundSpreadSheetButtonPushed(app, event)
            round = str2num(app.Selected_Round);
            switch round
                case 6
                    winopen("1654 Summary Tables.xls");
                case 7
                    winopen("1706 Summary Tables.xls");
                case 8
                    winopen("1965 Summary Tables.xls");
                otherwise
                    uialert(app.UIFigure,"No Spreadsheet Data is available for this Round","File Not Found");
            end
            
        end

        % Button pushed function: BeginnerGuideButton
        function BeginnerGuideButtonPushed(app, event)
            winopen("Tire Data App Guide.pdf")
        end

        % Button pushed function: ExporttoExcelButton
        function ExporttoExcelButtonPushed(app, event)
            %export data in app.exportData
            x_vals = app.exportData{1};
            y_vals = app.exportData{2};
            for i = 1:size(x_vals)
                finalArr = [{'X Vals'},{'Y Vals'}];
                temp = [x_vals{1},y_vals{1}];
                temp = num2cell(temp);
                finalArr = [finalArr;temp];
                writecell(finalArr, 'Data Used.xls', "Sheet", i,'Range','A1');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RoundDropDownLabel = uilabel(app.UIFigure);
            app.RoundDropDownLabel.Text = 'Round';
            app.RoundDropDown = uidropdown(app.UIFigure);
            app.RoundDropDown.Items = {'', '6', '7', '8'};
            app.RoundDropDown.ValueChangedFcn = createCallbackFcn(app, @RoundDropDownValueChanged, true);
            app.RoundDropDown.Value = '';
            app.TireDropDownLabel = uilabel(app.UIFigure);
            app.TireDropDownLabel.Text = 'Tire';
            app.TireDropDown = uidropdown(app.UIFigure);
            app.TireDropDown.Items = {'Choose Round'};
            app.TireDropDown.ValueChangedFcn = createCallbackFcn(app, @TireDropDownValueChanged, true);
            app.TireDropDown.Value = 'Choose Round';
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.UIFigure);
            app.XaxisDropDown.Items = {' ', 'AMBTMP', 'ET', 'FX', 'FY', 'FZ', 'IA', 'MX', 'MZ', 'N', 'NFX', 'NFY', 'P', 'RE', 'RL', 'RST', 'SA', 'SL', 'SR', 'TSTC', 'TSTI', 'TSTO', 'V'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = ' ';
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.UIFigure);
            app.YaxisDropDown.Items = {' ', 'AMBTMP', 'ET', 'FX', 'FY', 'FZ', 'IA', 'MX', 'MZ', 'N', 'NFX', 'NFY', 'P', 'RE', 'RL', 'RST', 'SA', 'SL', 'SR', 'TSTC', 'TSTI', 'TSTO', 'V'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = ' ';
            app.HoldOnSwitchLabel = uilabel(app.UIFigure);
            app.HoldOnSwitchLabel.Text = 'Hold On';
            app.HoldOnSwitch = uiswitch(app.UIFigure, 'slider');
            app.HoldOnSwitch.ValueChangedFcn = createCallbackFcn(app, @HoldOnSwitchValueChanged, true);
            app.HoldOnSwitch.Tooltip = {' '};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.HyTechTireDataVisualizationToolLabel = uilabel(app.UIFigure);
            app.HyTechTireDataVisualizationToolLabel.Text = 'HyTech Tire Data Visualization Tool';
            app.RunDropDownLabel = uilabel(app.UIFigure);
            app.RunDropDownLabel.Text = 'Run';
            app.RunDropDown = uidropdown(app.UIFigure);
            app.RunDropDown.Items = {'', '1', '2', '3', '4', '5', '6'};
            app.RunDropDown.ValueChangedFcn = createCallbackFcn(app, @RunDropDownValueChanged, true);
            app.RunDropDown.Value = '';
            app.RunGuideButton = uibutton(app.UIFigure, 'push');
            app.RunGuideButton.ButtonPushedFcn = createCallbackFcn(app, @RunGuideButtonPushed, true);
            app.RunGuideButton.Text = 'Run Guide';
            app.SameRunButton = uibutton(app.UIFigure, 'push');
            app.SameRunButton.ButtonPushedFcn = createCallbackFcn(app, @SameRunButtonPushed, true);
            app.SameRunButton.Text = 'Same Run';
            app.ZoomSwitchLabel = uilabel(app.UIFigure);
            app.ZoomSwitchLabel.Text = 'Zoom';
            app.ZoomSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZoomSwitch.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchValueChanged, true);
            app.PanSwitchLabel = uilabel(app.UIFigure);
            app.PanSwitchLabel.Text = 'Pan';
            app.PanSwitch = uiswitch(app.UIFigure, 'slider');
            app.PanSwitch.ValueChangedFcn = createCallbackFcn(app, @PanSwitchValueChanged, true);
            app.ChangingVariableDropDownLabel = uilabel(app.UIFigure);
            app.ChangingVariableDropDownLabel.Text = 'Changing Variable';
            app.ChangingVariableDropDown = uidropdown(app.UIFigure);
            app.ChangingVariableDropDown.Items = {'Tire', 'AMBTMP', 'ET', 'FX', 'FY', 'FZ', 'IA', 'MX', 'MZ', 'N', 'NFX', 'NFY', 'P', 'RE', 'RL', 'RST', 'SA', 'SL', 'SR', 'TSTC', 'TSTI', 'TSTO', 'V'};
            app.ChangingVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangingVariableDropDownValueChanged, true);
            app.ChangingVariableDropDown.Tooltip = {' '};
            app.ChangingVariableDropDown.Value = 'Tire';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.ColdtoHotSweepsLabel = uilabel(app.UIFigure);
            app.ColdtoHotSweepsLabel.Text = 'Cold to Hot Sweeps?';
            app.ColdtoHotSweepsSwitch = uiswitch(app.UIFigure, 'slider');
            app.ColdtoHotSweepsSwitch.Items = {'No', 'Yes'};
            app.ColdtoHotSweepsSwitch.ValueChangedFcn = createCallbackFcn(app, @ColdtoHotSweepsSwitchValueChanged, true);
            app.ColdtoHotSweepsSwitch.Value = 'No';
            app.PlotTypeSwitchLabel = uilabel(app.UIFigure);
            app.PlotTypeSwitchLabel.Text = 'Plot Type';
            app.PlotTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotTypeSwitch.Items = {'Polyfit', 'Raw Data'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotTypeSwitchValueChanged, true);
            app.PlotTypeSwitch.Value = 'Raw Data';
            app.SpringRateTestsSwitchLabel = uilabel(app.UIFigure);
            app.SpringRateTestsSwitchLabel.Text = 'Spring Rate Tests?';
            app.SpringRateTestsSwitch = uiswitch(app.UIFigure, 'slider');
            app.SpringRateTestsSwitch.Items = {'No', 'Yes'};
            app.SpringRateTestsSwitch.ValueChangedFcn = createCallbackFcn(app, @SpringRateTestsSwitchValueChanged, true);
            app.SpringRateTestsSwitch.Value = 'No';
            app.PullOutFigureButton = uibutton(app.UIFigure, 'push');
            app.PullOutFigureButton.ButtonPushedFcn = createCallbackFcn(app, @PullOutFigureButtonPushed, true);
            app.PullOutFigureButton.Text = 'Pull Out Figure';
            app.RoundSpreadSheetButton = uibutton(app.UIFigure, 'push');
            app.RoundSpreadSheetButton.ButtonPushedFcn = createCallbackFcn(app, @RoundSpreadSheetButtonPushed, true);
            app.RoundSpreadSheetButton.Tooltip = {'Contains a complete description of the test procedures. Tire spring rate'; ' cornering stiffness'; ' probe temperatures'; ' tire weights'; ' and wheel weights are also included.'};
            app.RoundSpreadSheetButton.Text = 'Round Spreadsheet';
            app.BeginnerGuideButton = uibutton(app.UIFigure, 'push');
            app.BeginnerGuideButton.ButtonPushedFcn = createCallbackFcn(app, @BeginnerGuideButtonPushed, true);
            app.BeginnerGuideButton.Text = 'Beginner Guide';
            app.ExporttoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Text = 'Export to Excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tire_data_visualization_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end